classdef hmwork_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        extractionButton  matlab.ui.control.Button
        computeButton     matlab.ui.control.Button
        loadButton        matlab.ui.control.Button
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            im = imread('./logo_csse.png');
            imshow(im, 'Parent', app.UIAxes);
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            im2 = imread('./logo_csse.png');
            imgray = rgb2gray(im2);
            imbw = imbinarize(imgray);
            imshow(imbw*255, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: extractionButton
        function extractionButtonPushed(app, event)
            im3 = imread('./logo_csse.png');
            imgray2 = rgb2gray(im3);
            imbw = edge(imgray2, 'canny');
            imshow(imbw, 'Parent', app.UIAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.extractionButton = uibutton(app.UIFigure, 'push');
            app.extractionButton.ButtonPushedFcn = createCallbackFcn(app, @extractionButtonPushed, true);
            app.extractionButton.Text = 'extraction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hmwork_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end